classdef projetoPDS_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RemoverTomButton      matlab.ui.control.Button
        ReproduzirButton      matlab.ui.control.Button
        Lamp_3                matlab.ui.control.Lamp
        Lamp_2                matlab.ui.control.Lamp
        MudarTomButton        matlab.ui.control.Button
        SustenidoSwitch       matlab.ui.control.Switch
        SustenidoSwitchLabel  matlab.ui.control.Label
        ReproduzirButton_2    matlab.ui.control.Button
        NotaButtonGroup       matlab.ui.container.ButtonGroup
        GButton               matlab.ui.control.RadioButton
        FButton               matlab.ui.control.RadioButton
        EButton               matlab.ui.control.RadioButton
        DButton               matlab.ui.control.RadioButton
        AButton               matlab.ui.control.RadioButton
        BButton               matlab.ui.control.RadioButton
        CButton               matlab.ui.control.RadioButton
        OitavaButtonGroup     matlab.ui.container.ButtonGroup
        Button_5              matlab.ui.control.RadioButton
        Button_4              matlab.ui.control.RadioButton
        Button_3              matlab.ui.control.RadioButton
        Button_2              matlab.ui.control.RadioButton
        Button                matlab.ui.control.RadioButton
        Lamp                  matlab.ui.control.Lamp
        Label                 matlab.ui.control.Label
        ImportarButton        matlab.ui.control.Button
        GravarButton          matlab.ui.control.Button
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        frequencias;
        audio;
        audio_modificado;
        Fs;
        t_s;
        line;
        collumn;
        f_tom;
        espectro;
        espectro_mudado;
        f;
        jose; % Carinha encarregado de manter um tamanho acima do espectro pro plot do eixo y 
    end
    
  methods (Access = private)
    % Gerar aleatoriamente a cor
    function [corzinha1, corzinha2] = corzinhaRandom(~)
        corzinhas = {
            '#FF78C4', '#FFBDF7', '#E1AEFF', '#E893CF', '#FFAAC9', '#A267AC'; ...  % tons rosinha
            '#CD1818', '#7E1717', '#B70404', '#E76161', '#E06469', '#FF6D60'; ...  % tons vermelhinhos
            '#05BFDB', '#088395', '#00FFCA', '#ADE4DB', '#6DA9E4', '#070A52'; ...  % tons azulzinho
            '#6554AF', '#9575DE', '#E1AEFF', '#9336B4', '#9376E0', '#C4B0FF'; ...  % tons roxinhos
            '#17594A', '#16FF00', '#A0C49D', '#C4D7B2', '#8EAC50', '#D3D04F'; ...  % tons verdin
            '#B799FF', '#AEE2FF', '#ACBCFF', '#FFBDF7', '#C7E9B0', '#8D9EFF'; ...  % tons mais pasteis
            '#8D7B68', '#A4907C', '#C8B6A6', '#482121', '#A86464', '#D5B4B4'; ...  % tons marrom
            '#D9ACF5', '#EA8FEA', '#FFAACF', '#C1AEFC', '#94D0CC', '#D35D6E'}; ... % tons fofinhos claros
        
        % Selecionar uma categoria de tons aleatórios
        categorinha = randi(size(corzinhas, 1));
        corzinCat = corzinhas(categorinha, :);
        
        % Selecionar duas cores aleatórias dentro da categoria
        indi1 = randi(numel(corzinCat));
 
        % Selecionar a segunda cor aleatória diferente da primeira
        indi2 = randi(numel(corzinCat));
        while (indi2 == indi1)
            indi2 = randi(numel(corzinCat));
        end
        % Pega a cor desse indice aleatório
        corzinha1 = corzinCat(indi1);
        corzinha2 = corzinCat{indi2};
    
    end

        % Obtenção do Espectro
        function [F, f, espectro] = obterEspectro(~, x, t)
        
            F = fft(x);
            
            magEspectro = abs(F); % Valor absoluto da transformada de Fourier
            magEspectro = magEspectro(1:end/2+1); % Divide o espectro
            magEspectro(2:end) = magEspectro(2:end)*2;
            
            espectro = magEspectro; 
            Ts = t(2) - t(1); % Intervalo de amostragem
            fs = 1/Ts; % Taxa de amostragem
            
            f = (0:length(magEspectro) - 1)*(fs/2)/length(magEspectro); % Frequências do espectro
        
        end
        
        % Verificação da nota predominante da frequencia
        function [f_tom, nota, oitava] = Nota(app, Xabs, Fs)
        
            freqs = (0:length(Xabs)-1) * (Fs / (2*(length(Xabs) - 1))); % calcula as frequências para todo o espectro, não apenas a metade. 
            
            % Encontrar a frequência predominante
            [~, maxIndece] = max(Xabs); % Encontra o valor máximo do espectro e seu índice correspondente
            f_tom = freqs(maxIndece); % Frequência predominante correspondente ao indice maximo
            
            % Tabela de notas com as frequências correspondentes
            notas = {'C', 'C#', 'D', 'D#', 'E', 'F', 'F#', 'G', 'G#', 'A', 'A#', 'B'};
            
            % Encontrar a nota e oitava correspondente à frequência predominante
            %linha = notas, coluna = frequencias
            [linha, coluna] = find(f_tom >= app.frequencias, 1, 'last'); % Encontra o indice correspondente a nota e frequencia
            nota = notas{linha}; % Nota predominante
            oitava = coluna;     % Oitava predominante
            
            % Exibir a nota e oitava encontradas
            fprintf('Maior frequencia: %.3f Hz . Nota predominante: %s na oitava %d\n', f_tom, nota, oitava);
            
        end
        
        function [audio_modificado] = modificarEspectro(app, audio, t, Fs, f_alvo)
            
            [F, ~, app.espectro] = obterEspectro(app, audio, t);
            [app.f_tom, ~, ~] = Nota(app, app.espectro, Fs);
        
            f_dif = ceil(f_alvo - app.f_tom); % Diferença entre a frequencia desejada e a de referência
            f_dif = ceil((length(app.espectro)/(Fs/2))*f_dif); % Escala a diferença com o vetor espectro
        
            if f_dif > 0
                for c = 1:f_dif
                    espectro_modificado(c) = 0; % Atenuar a frequência do espectro
                    espectro_modificado((length(F)+1)-c) = 0;
                end
                
                for c = 1:((length(F)/2) - f_dif)
                    espectro_modificado(c + f_dif) = F(c); % Desloca o espectro original 
                    espectro_modificado((length(F)+1) - c - f_dif) = F((length(F)+1)-c);
                
                end
            else
                espectro_modificado((length(F)/2)) = 0;
                for c = 1:abs(f_dif)
                    espectro_modificado((length(F)/2)-c) = 0;
                    espectro_modificado((length(F)/2)+c) = 0;
                end
        
                for c = 1:((length(F)/2) - abs(f_dif))
                    espectro_modificado(c) = F(c + abs(f_dif));
                    espectro_modificado((length(F)+1)-c) = F((length(F)+1) - c - abs(f_dif));
                end
            end
            
            audio_modificado = ifft(espectro_modificado, 'symmetric');
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
       
            % Obtenha o tamanho da tela do monitor principal
            screensize = get(groot, 'Screensize');
            screenwidth = screensize(3);
            screenheight = screensize(4);
            
            % Obtenha o tamanho da janela do App Designer
            appwidth = app.UIFigure.Position(3);
            appheight = app.UIFigure.Position(4);
            
            % Calcule as coordenadas x e y para centralizar a janela
            x = (screenwidth - appwidth) / 2;
            y = (screenheight - appheight) / 2;
            
            % Defina a posição da janela para as coordenadas centralizadas
            app.UIFigure.Position(1) = x;
            app.UIFigure.Position(2) = y;

            app.frequencias = [ 32, 65, 130, 261, 523;     % Nota C
                                34, 69, 138, 277, 554;     % Nota C#
                                36, 73, 146, 293, 587;     % Nota D
                                38, 77, 155, 311, 622;     % Nota D#
                                41, 82, 164, 329, 659;     % Nota E
                                43, 87, 174, 349, 698;     % Nota F
                                46, 92, 185, 369, 739;     % Nota F#
                                49, 98, 196, 392, 784;     % Nota G
                                52, 104, 208, 415, 830;    % Nota G#
                                55, 110, 220, 440, 880;    % Nota A
                                58, 116, 233, 466, 932;    % Nota A#
                                61, 123, 246, 493, 987; ]; % Nota B

        end

        % Button pushed function: GravarButton
        function GravarButtonPushed(app, event)

            app.Lamp.Color = [0, 1, 0]; % Muda a cor da lampada para verde

            Tempo = 5; % 5 segundos de gravação
            
            FS = 8000;  % Frequência de amostragem em 8 kHz
            Nbits = 16; % Sinal de 16 bits
            Canais = 1; % 1 = mono, 2 = stereo
            r = audiorecorder(FS, Nbits, Canais); % Preparar para gravar
            record(r); 
            pause(Tempo);
            stop(r);
            Y = getaudiodata(r, 'double'); % Guardar em uma variável double
           
            N = length(Y);
            t = 0:1/FS:(N-1)/FS;

            save('aa.mat','Y', 'FS');

            app.Lamp.Color = [1, 0, 0]; % Muda a cor da lampada para vermelho

            app.Fs = FS;
            app.audio = Y;
            app.t_s = t/app.Fs;

            % Chamada para a função corzinhaRandom
            [corzinha1] = app.corzinhaRandom();
            
            % Verificação das cores retornadas pela função
            disp(corzinha1);
            
            % Conversão das cores em strings
            corzinha1 = string(corzinha1);
            
            [~, app.f, app.espectro] = obterEspectro(app, app.audio, app.t_s);
            [app.f_tom, ~, ~] = Nota(app, app.espectro, app.Fs);
            [app.jose, ~] = max(app.espectro); % Encontra o valor máximo do espectro
            
            % Plotando o gráfico do espectro
            plot(app.UIAxes, app.f, app.espectro, 'Color', corzinha1);
            xlabel(app.UIAxes, 'Frequência (Hz)');
            ylabel(app.UIAxes, 'Magnitude');
            ylim(app.UIAxes, [0 app.jose+30]);
            title(app.UIAxes, 'Espectro de Frequência do Áudio');
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';


            % Torna o botão de tocar o primeiro áudio visivel apenas depois de importa-lo
            app.ReproduzirButton.Enable = 'on';
            app.ReproduzirButton.Visible = 'on';

        end

        % Button pushed function: ImportarButton
        function ImportarButtonPushed(app, event)
            % Lendo o áudio gravado
            X = load('aa.mat');
            
            app.audio = X.Y; % Extrair o audio do audio
            app.Fs = X.FS;   % Extrair FS do audio (frequência da taxa de amostragem)
            
            t = 1:length(app.audio); % Numero total de amostras (como vetor 1 ate o total)
            t = transpose(t); 
            
            app.t_s = t/app.Fs;  % Duração do áudio em segundos
            
            % Chamada para a função corzinhaRandom
            [corzinha1] = app.corzinhaRandom();
            
            % Verificação das cores retornadas pela função
            disp(corzinha1);
            
            % Conversão das cores em strings
            corzinha1 = string(corzinha1);
 
            [~, app.f, app.espectro] = obterEspectro(app, app.audio, app.t_s);
            [app.f_tom, ~, ~] = Nota(app, app.espectro, app.Fs);
            [app.jose, ~] = max(app.espectro); % Encontra o valor máximo do espectro
          
            % Plotando Gráfico
            plot(app.UIAxes, app.f, app.espectro, 'Color', corzinha1);
            xlabel(app.UIAxes, 'Frequência (Hz)');
            ylabel(app.UIAxes, 'Magnitude');
            ylim(app.UIAxes, [0 app.jose+30]);
            title(app.UIAxes, 'Espectro de Frequência do Áudio');
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';

            % Torna o botão de tocar o primeiro áudio visivel apenas depois de importa-lo
            app.ReproduzirButton.Enable = 'on';
            app.ReproduzirButton.Visible = 'on';

        end

        % Button pushed function: MudarTomButton
        function MudarTomButtonPushed(app, event)
            % Torna o botão de tocar o segundo áudio visivel apenas depois de criá-lo
            app.ReproduzirButton_2.Enable = 'on';
            app.ReproduzirButton_2.Visible = 'on';

            NotaButtonGroupSelectionChanged(app, event)
            OitavaButtonGroupSelectionChanged(app, event)

            f_alvo = app.frequencias(app.line, app.collumn); % Frequência desejada                        
            [app.audio_modificado] = modificarEspectro(app, app.audio, app.t_s, app.Fs, f_alvo);

            [~, f2, espectro2] = obterEspectro(app, app.audio_modificado, app.t_s);
            [~, ~, ~] = Nota(app, espectro2, app.Fs);
          
            % Chamada para a função corzinhaRandom
            [corzinha1] = app.corzinhaRandom();
            % Verificação da cor retornada pela função
            disp(corzinha1);
            % Conversão das cores em strings
            corzinha1 = string(corzinha1);
            
            % Plotando o gráfico do espectro novo
            cla(app.UIAxes_2,'reset');
            plot(app.UIAxes_2, f2, espectro2, 'Color', corzinha1);
            xlabel(app.UIAxes_2, 'Frequência (Hz)');
            ylabel(app.UIAxes_2, 'Magnitude');
            ylim(app.UIAxes_2, [0 app.jose+30]);
            title(app.UIAxes_2, 'Espectro de Frequência do Áudio Modificado');
            app.UIAxes_2.XGrid = 'on';
            app.UIAxes_2.YGrid = 'on';

        end

        % Button pushed function: RemoverTomButton
        function RemoverTomButtonPushed(app, event)
            % Torna o botão de tocar o segundo áudio visivel apenas depois de criá-lo
            app.ReproduzirButton_2.Enable = 'on';
            app.ReproduzirButton_2.Visible = 'on';
       
            NotaButtonGroupSelectionChanged(app, event);
            OitavaButtonGroupSelectionChanged(app, event);
            
            % Remover
            rejeitamu = {
                [31 33], [63 67], [126 134], [253 269], [508 538];       % Nota C
                [33 35], [67 71], [134 142], [271 285], [538 570];       % Nota C#
                [35 37], [71 75], [142 150], [285 301], [570 603];       % Nota D
                [37 39], [75 79], [151 159], [302 320], [604 640];       % Nota D#
                [40 42], [80 84], [160 168], [320 338], [640 678];       % Nota E
                [42 44], [85 89], [170 178], [339 359], [678 718];       % Nota F
                [45 47], [90 94], [180 190], [359 379], [719 760];       % Nota F#
                [48 50], [96 100], [191 201], [381 403], [762 806];      % Nota G
                [51 53], [102 106], [202 214], [403 427], [806 854];     % Nota G#
                [54 56], [108 112], [214 226], [427 453], [855 905];     % Nota A
                [57 59], [114 118], [227 239], [453 479], [906 958];     % Nota A#
                [60 62], [121 125], [240 252], [479 507], [959 1015]     % Nota B
            };

            % Transição
            passaras = {
                [31-2 33+2], [63-4 67+4], [126-6 134+6], [253-8 269+8], [508-10 538+10];    % Nota C
                [33-2 35+2], [67-4 71+4], [134-6 142+6], [271-8 285+8], [538-10 570+10];    % Nota C#
                [35-2 37+2], [71-4 75+4], [142-6 150+6], [285-8 301+8], [570-10 603+10];    % Nota D
                [37-2 39+2], [75-4 79+4], [151-6 159+6], [302-8 320+8], [604-10 640+10];    % Nota D#
                [40-2 42+2], [80-4 84+4], [160-6 168+6], [320-8 338+8], [640-10 678+10];    % Nota E
                [42-2 44+2], [85-4 89+4], [170-6 178+6], [339-8 359+8], [678-10 718+10];    % Nota F
                [45-2 47+2], [90-4 94+4], [180-6 190+6], [359-8 379+8], [719-10 760+10];    % Nota F#
                [48-2 50+2], [96-4 100+4], [191-6 201+6], [381-8 403+8], [762-10 806+10];   % Nota G
                [51-2 53+2], [102-4 106+4], [202-6 214+6], [403-8 427+8], [806-10 854+10];  % Nota G#
                [54-2 56+2], [108-4 112+4], [214-6 226+6], [427-8 453+8], [855-10 905+10];  % Nota A
                [57-2 59+2], [114-4 118+4], [227-6 239+6], [453-8 479+8], [906-10 958+10];  % Nota A#
                [60-2 62+2], [121-4 125+4], [240-6 252+6], [479-8 507+8], [959-10 1015+10]; % Nota B
            };

            atenuacaozinha = [ 20, 20, 19, 16, 13]; 

            %Butterworth
            freqNyquist = app.Fs / 2;                         % Frequência de Nyquist
            Rp = 1;                                           % Ripple na banda passante em dB
            Rs = atenuacaozinha(app.collumn);                 % Atenuação na banda de rejeição em dB
            Wp = cell2mat(rejeitamu(app.line, app.collumn));  % Frequências de rejeição
            Ws = cell2mat(passaras(app.line, app.collumn));   % Frequências de transição
          
            % Projeto do filtro IIR utilizando a função butter
            [n, Wn] = buttord(Wp/freqNyquist, Ws/freqNyquist, Rp, Rs);
            [b, a] = butter(n, Wn, 'stop');
            
            % Aplicação do filtro IIR no sinal com ruído
            app.audio_modificado = filtfilt(b, a, app.audio); % filtragem bidirecional/sem atraso de fase.
            
            % Chamada para a função corzinhaRandom
            [corzinha1] = app.corzinhaRandom();
            % Verificação da cor retornada pela função
            disp(corzinha1);
            % Conversão das cores em strings
            corzinha1 = string(corzinha1);

            [~, f2, espectro2] = obterEspectro(app, app.audio_modificado, app.t_s);
            [~, ~, ~] = Nota(app, espectro2, app.Fs);

            % Plotando o gráfico do espectro novo
            cla(app.UIAxes_2,'reset');
            plot(app.UIAxes_2, f2, espectro2, 'Color', corzinha1);
            xlabel(app.UIAxes_2, 'Frequência (Hz)');
            ylabel(app.UIAxes_2, 'Magnitude');
            ylim(app.UIAxes_2, [0 app.jose+30]);
            title(app.UIAxes_2, 'Espectro de Frequência do Áudio Modificado');
            app.UIAxes_2.XGrid = 'on';
            app.UIAxes_2.YGrid = 'on';
         
        end

        % Selection changed function: NotaButtonGroup
        function NotaButtonGroupSelectionChanged(app, event)
           
            if app.CButton.Value == 1 % C
                
                if app.SustenidoSwitch.Value == "Não"
                    
                    app.line = 1;
                    app.SustenidoSwitch.Enable = 'on';
                    SustenidoSwitchValueChanged(app, event);

                else    % C#
                    app.line = 2;
                end

            elseif app.DButton.Value == 1 % D

                if app.SustenidoSwitch.Value == "Não"

                    app.line = 3;
                    app.SustenidoSwitch.Enable = 'on';
                    SustenidoSwitchValueChanged(app, event);
                
                else    %  D#
                    app.line = 4;
                end           

            elseif app.EButton.Value == 1 % E
                app.line = 5;
                app.SustenidoSwitch.Enable = 'off';
                app.SustenidoSwitch.Value = 'Não';
                SustenidoSwitchValueChanged(app, event);

            elseif app.FButton.Value == 1 % F

                if app.SustenidoSwitch.Value == "Não"

                    app.line = 6;
                    app.SustenidoSwitch.Enable = 'on';
                    SustenidoSwitchValueChanged(app, event);
                
                else    % F#
                    app.line = 7;
                end

            elseif app.GButton.Value == 1 % G

                if app.SustenidoSwitch.Value == "Não"

                    app.line = 8;
                    app.SustenidoSwitch.Enable = 'on';
                    SustenidoSwitchValueChanged(app, event);
                
                else    % G#
                    app.line = 9;
                end                
            
            elseif app.AButton.Value == 1 % A

                if app.SustenidoSwitch.Value == "Não"

                    app.line = 10;
                    app.SustenidoSwitch.Enable = 'on';
                    SustenidoSwitchValueChanged(app, event);
                
                else    % A#
                    app.line = 11;
                end

            elseif app.BButton.Value == 1 % B
                app.line = 12;
                app.SustenidoSwitch.Enable = 'off';
                app.SustenidoSwitch.Value = 'Não';
                SustenidoSwitchValueChanged(app, event);

            end
            
        end

        % Selection changed function: OitavaButtonGroup
        function OitavaButtonGroupSelectionChanged(app, event)
      
            if app.Button.Value == 1 
                app.collumn = 1; 
        
            elseif app.Button_2.Value == 1
                app.collumn = 2; 
        
            elseif app.Button_3.Value == 1
                app.collumn = 3; 

            elseif app.Button_4.Value == 1
                app.collumn = 4; 

            elseif app.Button_5.Value == 1
                app.collumn = 5; 
            end

        end

        % Value changed function: SustenidoSwitch
        function SustenidoSwitchValueChanged(app, event)

            if app.SustenidoSwitch.Value == "Sim"
                app.Lamp_2.Visible =  'off';
                app.Lamp_3.Visible = 'on';

            else
                app.Lamp_2.Visible =  'on';
                app.Lamp_3.Visible =  'off';

                if app.SustenidoSwitch.Enable == "off"
                    app.Lamp_2.Enable =  'off';
                else
                    app.Lamp_2.Enable =  'on';
                end

            end

        end

        % Button pushed function: ReproduzirButton
        function ReproduzirButtonPushed(app, event)
            % Chamada para a função corzinhaRandom
            [corzinha1] = app.corzinhaRandom();
            % Verificação da cor retornada pela função
            disp(corzinha1);
            % Conversão das cores em strings
            corzinha1 = string(corzinha1);
            % Plotando o gráfico do áudio modificado
            cla(app.UIAxes,'reset');
            plot (app.UIAxes, app.t_s, app.audio, 'Color', corzinha1);
            xlim(app.UIAxes, [0 app.t_s(end)]);
            xlabel(app.UIAxes, 'Tempo (s)');
            ylabel(app.UIAxes, 'Amplitude do Sinal');
            title(app.UIAxes, 'Forma de onda do Áudio ');
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            % Tocar o audio
            sound(app.audio);

        end

        % Button pushed function: ReproduzirButton_2
        function ReproduzirButton_2Pushed(app, event)
            % Chamada para a função corzinhaRandom
            [corzinha1] = app.corzinhaRandom();
            % Verificação da cor retornada pela função
            disp(corzinha1);
            % Conversão das cores em strings
            corzinha1 = string(corzinha1);
            % Plotando o gráfico do áudio modificado
            cla(app.UIAxes_2,'reset');
            plot (app.UIAxes_2, app.t_s, app.audio_modificado, 'Color', corzinha1);
            xlim(app.UIAxes_2, [0 app.t_s(end)]);
            xlabel(app.UIAxes_2, 'Tempo (s)');
            ylabel(app.UIAxes_2, 'Amplitude do Sinal');
            title(app.UIAxes_2, 'Forma de onda do Áudio Modificado');
            app.UIAxes_2.XGrid = 'on';
            app.UIAxes_2.YGrid = 'on';
            % Tocar o audio
            sound(app.audio_modificado);
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.GravarButton = uibutton(app.UIFigure, 'push');
            app.GravarButton.ButtonPushedFcn = createCallbackFcn(app, @GravarButtonPushed, true);
            app.GravarButton.Icon = fullfile(pathToMLAPP, 'gravarzinho.png');
            app.GravarButton.Text = 'Gravar';
            app.ImportarButton = uibutton(app.UIFigure, 'push');
            app.ImportarButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarButtonPushed, true);
            app.ImportarButton.Icon = fullfile(pathToMLAPP, 'importazinho.png');
            app.ImportarButton.Text = 'Importar';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.OitavaButtonGroup = uibuttongroup(app.UIFigure);
            app.OitavaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OitavaButtonGroupSelectionChanged, true);
            app.OitavaButtonGroup.Title = 'Oitava';
            app.Button = uiradiobutton(app.OitavaButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.OitavaButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.OitavaButtonGroup);
            app.Button_3.Text = '3';
            app.Button_4 = uiradiobutton(app.OitavaButtonGroup);
            app.Button_4.Text = '4';
            app.Button_5 = uiradiobutton(app.OitavaButtonGroup);
            app.Button_5.Text = '5';
            app.NotaButtonGroup = uibuttongroup(app.UIFigure);
            app.NotaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NotaButtonGroupSelectionChanged, true);
            app.NotaButtonGroup.Title = 'Nota';
            app.CButton = uiradiobutton(app.NotaButtonGroup);
            app.CButton.Text = 'C (Dó)';
            app.CButton.Value = true;
            app.BButton = uiradiobutton(app.NotaButtonGroup);
            app.BButton.Text = 'B (Si)';
            app.AButton = uiradiobutton(app.NotaButtonGroup);
            app.AButton.Text = 'A (Lá) ';
            app.DButton = uiradiobutton(app.NotaButtonGroup);
            app.DButton.Text = 'D (Ré)';
            app.EButton = uiradiobutton(app.NotaButtonGroup);
            app.EButton.Text = 'E (Mi)';
            app.FButton = uiradiobutton(app.NotaButtonGroup);
            app.FButton.Text = 'F (Fá)';
            app.GButton = uiradiobutton(app.NotaButtonGroup);
            app.GButton.Text = 'G (Sol)';
            app.ReproduzirButton_2 = uibutton(app.UIFigure, 'push');
            app.ReproduzirButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReproduzirButton_2Pushed, true);
            app.ReproduzirButton_2.Icon = fullfile(pathToMLAPP, 'tocar.png');
            app.ReproduzirButton_2.Enable = 'off';
            app.ReproduzirButton_2.Visible = 'off';
            app.ReproduzirButton_2.Text = 'Reproduzir';
            app.SustenidoSwitchLabel = uilabel(app.UIFigure);
            app.SustenidoSwitchLabel.Text = 'Sustenido (#)';
            app.SustenidoSwitch = uiswitch(app.UIFigure, 'slider');
            app.SustenidoSwitch.Items = {'Não', 'Sim'};
            app.SustenidoSwitch.ValueChangedFcn = createCallbackFcn(app, @SustenidoSwitchValueChanged, true);
            app.SustenidoSwitch.Value = 'Não';
            app.MudarTomButton = uibutton(app.UIFigure, 'push');
            app.MudarTomButton.ButtonPushedFcn = createCallbackFcn(app, @MudarTomButtonPushed, true);
            app.MudarTomButton.Icon = fullfile(pathToMLAPP, 'grafico2.png');
            app.MudarTomButton.Text = 'Mudar Tom';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_3.Visible = 'off';
            app.ReproduzirButton = uibutton(app.UIFigure, 'push');
            app.ReproduzirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproduzirButtonPushed, true);
            app.ReproduzirButton.Icon = fullfile(pathToMLAPP, 'tocar.png');
            app.ReproduzirButton.Enable = 'off';
            app.ReproduzirButton.Visible = 'off';
            app.ReproduzirButton.Text = 'Reproduzir';
            app.RemoverTomButton = uibutton(app.UIFigure, 'push');
            app.RemoverTomButton.ButtonPushedFcn = createCallbackFcn(app, @RemoverTomButtonPushed, true);
            app.RemoverTomButton.Icon = fullfile(pathToMLAPP, 'remover.png');
            app.RemoverTomButton.Text = 'Remover Tom';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projetoPDS_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end